classdef PlacePrefDataGUI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlacePrefDataAnalysisUIFigure   matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadConfigYAMLMenu              matlab.ui.container.Menu
        RestartAppMenu                  matlab.ui.container.Menu
        QuitAppMenu                     matlab.ui.container.Menu
        CloseMATLABMenu                 matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        ClearCommandWindowMenu          matlab.ui.container.Menu
        ToggleAlwaysOnTopMenu           matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        RelatedProgramsMenu             matlab.ui.container.Menu
        NIDAQmxAudioPlayerMenu          matlab.ui.container.Menu
        AboutthisAppMenu                matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        DataSelectionTab                matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        DatasetsSelectorPanel           matlab.ui.container.Panel
        ProjectSelectionGridLayout      matlab.ui.container.GridLayout
        QuickActionsPanel               matlab.ui.container.Panel
        QuickActionGrid                 matlab.ui.container.GridLayout
        SplitMultipleArenaToolGrid      matlab.ui.container.GridLayout
        BatchSplitMultiArenaButton      matlab.ui.control.Button
        RunVideoTrackingToolGrid        matlab.ui.container.GridLayout
        BatchRunVideoTrackingButton     matlab.ui.control.Button
        GridLayout15_2                  matlab.ui.container.GridLayout
        CompressProjectFolderGrid       matlab.ui.control.Button
        GridLayout15                    matlab.ui.container.GridLayout
        OpenFolderInFileManager         matlab.ui.control.Button
        ProjectRootSelect               FolderSelectorWithDropdown
        DataProcessingPlatformPanel     matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        TrackingProviderInfoButton      matlab.ui.control.Image
        TrackingProviderLabel           matlab.ui.control.Label
        DataProcessingPlatformDropDown  matlab.ui.control.DropDown
        DatasetToolsPanel               matlab.ui.container.Panel
        DatasetToolsGrid                matlab.ui.container.GridLayout
        PopulationAnalysisPanel         matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        ActivitybyStimuliPopulationAnalysisButton  matlab.ui.control.Button
        PlacePreferencePopulationAnalysisButton  matlab.ui.control.Button
        ExperimentOverviewButton        matlab.ui.control.Button
        SubjectsDemographicsButton      matlab.ui.control.Button
        BatchDataSelectTree             matlab.ui.container.CheckBoxTree
        BatchDataSelectRootNode         matlab.ui.container.TreeNode
        SingleProjectPanel              matlab.ui.container.Panel
        SingleProjectGridLayout         matlab.ui.container.GridLayout
        SingleTrialRunTrackingRequiredGridLayout  matlab.ui.container.GridLayout
        RunTrackingPanel                matlab.ui.container.Panel
        GridLayout14_2                  matlab.ui.container.GridLayout
        TrackVideoButton                matlab.ui.control.Button
        MissingTrackingDataNotice       matlab.ui.control.Label
        StimuliDuringTrialLabel         matlab.ui.control.Label
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        NextTrialButton                 matlab.ui.control.Button
        PreviousTrialButton             matlab.ui.control.Button
        GridLayout17                    matlab.ui.container.GridLayout
        RefreshTrialListButton          matlab.ui.control.Button
        DataToolsTrialDropDown          matlab.ui.control.DropDown
        DataToolsTrialDropDownLabel     matlab.ui.control.Label
        SingleTrialPreprocessingRequiredGridLayout  matlab.ui.container.GridLayout
        PreprocessingRequiredPanel      matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        SplitMultipleArenaTrialButton   matlab.ui.control.Button
        MultipleArenaPreprocessingNotice  matlab.ui.control.Label
        SingleTrialDisplayGridLayout    matlab.ui.container.GridLayout
        MetadataPanel                   matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout8_2                   matlab.ui.container.GridLayout
        StimStartFrameEditField         matlab.ui.control.NumericEditField
        StimStartFrameEditFieldLabel    matlab.ui.control.Label
        HabituationsEditField           matlab.ui.control.NumericEditField
        HabituationsEditFieldLabel      matlab.ui.control.Label
        SpeakerFlippedEditField         matlab.ui.control.NumericEditField
        SpeakerFlippedEditFieldLabel    matlab.ui.control.Label
        SourceHistoryEditField          matlab.ui.control.EditField
        SourceHistoryEditFieldLabel     matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        GenotypeEditField               matlab.ui.control.EditField
        GenotypeEditFieldLabel          matlab.ui.control.Label
        StrainEditField                 matlab.ui.control.EditField
        StrainEditFieldLabel            matlab.ui.control.Label
        SexEditField                    matlab.ui.control.EditField
        SexEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        DateofBirthDatePicker           matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel      matlab.ui.control.Label
        AnimalIDEditField               matlab.ui.control.EditField
        AnimalIDEditFieldLabel          matlab.ui.control.Label
        CageCodeEditField               matlab.ui.control.EditField
        CageCodeEditFieldLabel          matlab.ui.control.Label
        GridLayout7_2                   matlab.ui.container.GridLayout
        PlacePreferencecenterpointButton  matlab.ui.control.Button
        GridLayout6                     matlab.ui.container.GridLayout
        DataToolsTrialRawDataSheetButton  matlab.ui.control.Button
        DataToolsTrialVideoPlayButton   matlab.ui.control.Button
        MasterMetadataTableDatasetSelect  PathSelector
        StimuliDirDataSelect            PathSelector
        AppStatusBarPanel               matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        AppStatusBarDesc                matlab.ui.control.Label
        AppStatusBarState               matlab.ui.control.Label
    end

    
    properties (Access = private, Hidden)
        % Store the PATH state before doing anything else!!!
        StartupPATH = path;  % The PATH state when the app started. Use for resets.

        % Trigger MATLAB to addpath the components
        dummylib0 = addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'imports')));
        dummylib1 = str2func('load_lib');
        dummycomp = addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'components')));

        AppWindowSize = [980,720]; % The default size of the main app window
    end

    %% Props for Internal Components
    properties (Access = private)
        % MouseMoveCallback (Name-Value): Each value inside should be a cell array 
        %   {@fcnhandle, args1, ...argsN} 
        % to be run in order when mouse move event fires
        % The callback function will have access to the current mouse
        % position relative to the figure by simply define it as the last
        % argument in the function definition
        % Example:
        % function printPos(comp, prefixText, currentPoints)
        % --> Register the callback as {@comp.printPos, prefixText}
        MouseMoveCallbackFcnStack = struct();


        % Store handles to app components here!
        % ...
    end

    %% Props for Internal Use and to be Shared with External Components
    properties (Access = public, Hidden)
        lastDirPath = pwd;

    end
    

    %% Props for Internal Data
    properties (Access = private)
        

    end


    %% (Input Args / SetObservable) 
    properties (Access = public, SetObservable, AbortSet)
        TrackingProvider; % Handle to the current @TrackingProvider subclass to provide a common interface for tracking-related tasks
    end

    properties (Access = public, SetObservable)
        AppConfigsPath = ''; % The current app config, loaded from a 'configs.yml' file
    end

    properties (Access = public, SetObservable)
        MasterMetadataTablePath = ''; % Path to MasterMetadataTable *.xlsx
        
        
    end


    %% Public App Data
    properties (Access = public)
        PopulationSummaryTable = table(); % The last summary table generated when user pressed Population Analysis
        PopulationSummaryTableInputHash = ''; % The PopulationCurrentlySelectedInputsHash right before the PopulationSummaryTable is generated: same input hash results in the same SummaryTable
    end

    
    %% Read-only Public App Data
    properties (SetAccess = private, AbortSet)
        Figure; % The handle to this app UIFigure;
    end


    %% Dependent App Data
    properties (Dependent)
        AppConfigs % Configuration loaded from io.config.loadConfigYaml()

        MasterMetadataTable; % The parsed metadata table, loaded with io.metadata.loadMasterMetadata()
        
        PopulationCurrentlySelectedInputsHash; % The current composite SHA-256 hash of (1) the checked trial nodes, (2) the stimdir path, (3) the app.MasterMetadataTable content
    end


    %% METHODS

    methods
        %% For Dependent Props

        function config = get.AppConfigs(app)
            config = io.config.loadConfigYaml(app.AppConfigsPath);
        end


        function metadata = get.MasterMetadataTable(app)
            metadata = io.metadata.loadMasterMetadata(app.MasterMetadataTablePath);
        end


        function hash = get.PopulationCurrentlySelectedInputsHash(app)
            checkedNodes = app.BatchDataSelectTree.CheckedNodes;
            checkedTrials = checkedNodes(arrayfun(@(x) isstruct(x.NodeData), checkedNodes)); % only the most inner child nodes are trialInfo structs
            trials = arrayfun(@(x) x.NodeData, checkedTrials);
            trialHash = DataHash(trials, 'SHA-256', 'array');

            stimdir = app.StimuliDirDataSelect.SelectedPaths;
            stimdir = char(stimdir); % this is okay since the component is set to only allow single value
            stimdirHash = DataHash(stimdir, 'SHA-256', 'array');

            masterMetadataHash = DataHash(app.MasterMetadataTable, 'SHA-256', 'array');

            hashcomp = strcat(string(trialHash), string(stimdirHash), string(masterMetadataHash));
            hash = DataHash(hashcomp, 'SHA-256', 'array');
        end
    end


    methods (Static)

        function mustBeTextOrEmpty(A)
            %MUSTBETEXTOREMPTY Validate that value is text or empty
            %   MUSTBETEXTOREMPTY(A) throws an error if A is not text or empty.
            
            if isempty(A)
                return;
            end
            
            mustBeText(A);
        end

        function [filename, filedir] = currentExecutable()
            %%CURRENTEXECUTABLE Determine full path to calling file
            %   In a deployed program, this will point to the application exe.
            %   In regular MATLAB, it will be the calling m-file.
            if isdeployed % Executable in stand-alone mode.
                [~, result] = system('path');
                exedir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                args = System.Environment.GetCommandLineArgs;
                relpath = char(args(1));
                [~,exename,ext] = fileparts(relpath);
                filename = fullfile(exedir,[exename,ext]);
                filedir = exedir;
            else % MATLAB mode.
                [filedir, filename, ext] = fileparts(utils.path.canonicalize(fullfile(fileparts(mfilename('fullpath')), '..', 'PlacePrefDataGUI.m')));
                filename = fullfile(filedir, [filename, ext]);
            end
        end

    end

    methods (Access = private)
        %% Basic UI Utilities

        function color = semanticColor(~, colorOrDesc)
            % Given a color value (rgb, hex, or a semantic description of
            % the color, return a validated MATLAB RGB color value
            % If the color cannot be validated, a default off-white color
            % is returned
            switch colorOrDesc
                case { "reset", "default" }
                    color = [0.94, 0.94, 0.94]; % off-white
                case { "busy", "running", "processing" }
                    color = [0.63, 0.49, 0.72]; % purple-ish
                case { "error", "err" }
                    color = [0.85, 0.50, 0.49]; % red
                case { "warn", "warning" }
                    color = [0.83, 0.58, 0.27]; % dark yellow
                case { "activeGreen" }
                    color = [0.62, 0.82, 0.48]; % light green
                case { "dullGray", "dullGrey" }
                    color = [0.8, 0.8, 0.8]; % dark-ish gray
                
                % More case here as needed!

                otherwise
                    try
                        color = validatecolor(colorOrDesc);
                    catch
                        color = [0.94, 0.94, 0.94];
                    end
            end
        end


        %% Events / Callbacks


        %% Data Selection
        
        function updateUIMetadata(app, sex, strain, genotype, dob, age, cagecode, id, source, stimuli, speakerFlipped, habituationDuration, stimStartFrame)
            app.SexEditField.Value = sex;
            app.StrainEditField.Value = strain;
            app.GenotypeEditField.Value = genotype;

            app.DateofBirthDatePicker.Value = dob;
            if isnan(age), age = -Inf; end
            app.AgeEditField.Value = age;

            app.CageCodeEditField.Value = cagecode;
            app.AnimalIDEditField.Value = id;

            app.SourceHistoryEditField.Value = source;
            app.StimuliDuringTrialLabel.Text = stimuli;

            app.SpeakerFlippedEditField.Value = double(speakerFlipped);
            app.HabituationsEditField.Value = habituationDuration;
            app.StimStartFrameEditField.Value = stimStartFrame;

        end

        function [sex, strain, genotype, dob, age, cagecode, id, source, stimuli, speakerFlipped, habituationDuration, stimStartFrame] = extractTrialMetadataFromMasterTable(app, masterMetadataTable, trialRowIdx, ethovisionXlsx, ethovisionTrialHeader)
            sex = 'Unknown'; strain = 'Unknown'; genotype = 'Unknown'; dob = NaT;
            age = 0; cagecode = 'Unknown'; id = 'Unknown'; source = ''; stimuli = '!!! UNKNOWN !!!';
            speakerFlipped = false; habituationDuration = 0; stimStartFrame = 1;

            if isempty(trialRowIdx) || isempty(masterMetadataTable) || ~istable(masterMetadataTable)
                return
            end

            sex = char(masterMetadataTable{trialRowIdx, 'ANIMAL_SEX'});
            strain = char(masterMetadataTable{trialRowIdx, 'ANIMAL_STRAIN'});
            genotype = char(masterMetadataTable{trialRowIdx, 'ANIMAL_GENOTYPE'});

            dob = masterMetadataTable{trialRowIdx, 'ANIMAL_DOB'};
            age = masterMetadataTable{trialRowIdx, 'ANIMAL_P_AGE'};
            if ~isnumeric(age)
                age = str2double(age);
            end

            
            cagecode = char(masterMetadataTable{trialRowIdx, 'CAGE_CODE'});
            id = char(masterMetadataTable{trialRowIdx, 'ANIMAL_ID'});
            source = char(masterMetadataTable{trialRowIdx, 'SOURCE_CODE'});

            stimuli = char(masterMetadataTable{trialRowIdx, 'STIMULUS_PROTOCOL'});
            speakerFlipped = logical(masterMetadataTable{trialRowIdx, 'SPEAKER_FLIPPED'});
            habituationDuration = masterMetadataTable{trialRowIdx, 'HABITUATION_DURATION_SEC'};
            if ~isnumeric(habituationDuration) % just in case matlab parses it as text
                habituationDuration = str2double(habituationDuration);
            end
            stimStartFrame = masterMetadataTable{trialRowIdx, 'STIM_START_FRAME'};
            if ~isnumeric(stimStartFrame)
                stimStartFrame = str2double(stimStartFrame);
            end
            if isempty(stimStartFrame) || isnan(stimStartFrame)
                habitdur = masterMetadataTable{trialRowIdx, 'HABITUATION_DURATION_SEC'};
                if ~isnumeric(habitdur)
                    habitdur = str2double(string(habitdur));
                end
                if isempty(habitdur) || isnan(habitdur)
                    habitdur = 0; % Default to start of trial
                end
                mediafile = app.TrackingProvider.mediaPathFromTrackingData(ethovisionXlsx, Options=struct('Header', ethovisionTrialHeader, 'MetadataTable', app.MasterMetadataTable));
                if isfile(mediafile)
                    v = VideoReader(mediafile);
                    fps = v.FrameRate;
                    stimStartFrame = habitdur * fps + 1;
                    clear v;
                end    
            end

            if isempty(stimStartFrame) || isnan(stimStartFrame)
                stimStartFrame = 1;
            end
        end


    end


    methods (Access = public)
        
        function SetBorderColor(app, color)
            if nargin < 2
                color = 'reset';
            end
            color_rgb = semanticColor(app, color);
            app.GridLayout.BackgroundColor = color_rgb;
        end

        function SetStatusBar(app, status, description)
            arguments
                app
                status {validator.mustBeTextOrEmpty} = ''
                description {validator.mustBeTextOrEmpty} = ''
            end

            if isempty(status)
                status = 'Ready';
            end

            app.AppStatusBarState.Text = string(status);
            app.AppStatusBarDesc.Text = string(description);
            if lower(status) == "ready"
                app.AppStatusBarPanel.BorderColor = [0.49,0.49,0.49];
            else
                app.AppStatusBarPanel.BorderColor = semanticColor(app, "busy");
            end
            
            drawnow
        end

        function RegisterMouseMoveCallback(app, callbackID, callback)
            arguments
                app
                callbackID (1,:) {mustBeValidVariableName}
                callback (1,:) {mustBeNonempty}
            end
            app.MouseMoveCallbackFcnStack.(callbackID) = callback;
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ConfigYAMLPath, RestorePathOnClose)
            % Save this app handle to the workspace so the user have the
            % option to forcefully kill/delete the app when there's
            % something unexpected
            assignin("base", "PlacePreferenceGUI", app);

            if nargin < 2
                ConfigYAMLPath = '';
            end

            if nargin < 3
                RestorePathOnClose = '';
            end
            if ~isempty(RestorePathOnClose) && (class(RestorePathOnClose) == "string" || class(RestorePathOnClose) == "char" || iscellstr(RestorePathOnClose))
                app.StartupPATH = RestorePathOnClose;
            end


            %% Show loader until the loading is done to avoid early interaction and obvious layout shift
            app.AppStatusBarState.Text = "INITIALIZING";
            app.AppStatusBarDesc.Text = "Starting up...";
            startupLoader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Initializing',...
                'Message',"Starting up the Program...", ...
                "Indeterminate", "on");
            drawnow

            
            %% Set App's Defaults and Initialize Variables
            app.Figure = app.PlacePrefDataAnalysisUIFigure;

            screensize = get(0,'screensize');
            screenW = screensize(3); screenH = screensize(4);
            if screenW < app.AppWindowSize(1)
                app.AppWindowSize(1) = screenW;
            end
            if screenH < app.AppWindowSize(2)
                app.AppWindowSize(2) = screenH;
            end


            %% Set Hidden Components
            app.PopulationAnalysisPanel.Parent = [];
            app.DatasetToolsGrid.RowHeight = {"1x"};
            app.PopulationAnalysisPanel.Layout.Row = 1;
            app.SingleProjectPanel.Layout.Row = 1;


            app.SplitMultipleArenaToolGrid.Parent = [];
            app.BatchSplitMultiArenaButton.Enable = "off";
            app.RunVideoTrackingToolGrid.Parent = [];
            app.BatchRunVideoTrackingButton.Enable = "off";

            app.SingleTrialPreprocessingRequiredGridLayout.Parent = [];
            app.SingleTrialRunTrackingRequiredGridLayout.Parent = [];

            % Some components won't be available / make sense to be visible in deployed app
            if isdeployed()
                app.CloseMATLABMenu.Visible = "off";


            end
            
            
            %% Load components, modules, and utilities
            startupLoader.Message = "Importing App's Dependencies";

            % Load external + prioritized libs first
            thismfileDir = fileparts(mfilename('fullpath'));
            run(fullfile(thismfileDir, 'imports', 'load_lib.m'))
            
            app.PlacePrefDataAnalysisUIFigure.Position = [(screenW-app.AppWindowSize(1))/2, (screenH-app.AppWindowSize(2))/2, app.AppWindowSize(1), app.AppWindowSize(2)];
            drawnow;


            % Make sure to set the current mfiledir back to the main mlapp
            thismfileDir = fileparts(mfilename('fullpath'));
            % Add main app's required paths
            requiredPaths = {'assets', 'utils', 'modules', 'callbacks'};
            for i=1:length(requiredPaths)
                thisdir = fullfile(thismfileDir, requiredPaths{i});
                if ~isfolder(thisdir), continue; end
                reqDir = dir(thisdir);
                reqDir = reqDir(~ismember({reqDir.name}, {'.', '..'}));
                reqDir = reqDir(arrayfun(@(x) x.isdir, reqDir));
                srcDirPaths = fullfile({reqDir.folder}, {reqDir.name});
                for j=1:length(srcDirPaths)
                    startupLoader.Message = ['Importing ', fullfile('src', reqDir(j).name)];
                    paths = genpath(srcDirPaths{j});
                    addpath(paths)
                end
            end

            % The entry point or executable is either:
            % - The ../PlacePrefDataGUI.m when running as M/MLAPP file
            % - The executable (binary/exe) when running a compiled/deployed app
            %   Note that in both case, this is different from ctfroot()
            [~, exedir] = app.currentExecutable();
            % Define folders relative to the entry point to add to runtime path
            requiredPaths = {};
            for i=1:length(requiredPaths)
                thisdir = fullfile(exedir, requiredPaths{i});
                if ~isfolder(thisdir), continue; end
                reqDir = dir(thisdir);
                reqDir = reqDir(~ismember({reqDir.name}, {'.', '..'}));
                reqDir = reqDir(arrayfun(@(x) x.isdir, reqDir));
                srcDirPaths = fullfile({reqDir.folder}, {reqDir.name});
                for j=1:length(srcDirPaths)
                    startupLoader.Message = ['Importing ', fullfile(reqDir(j).name)];
                    paths = genpath(srcDirPaths{j});
                    addpath(paths)
                end
            end


            %% Mount/Use components or modules after loading
            availableTrackingProvider = ui.trackingPlatforms.TrackingProvider.listAvailablePlatforms();
            if ~isempty(availableTrackingProvider)
                app.DataProcessingPlatformDropDown.Items = availableTrackingProvider;
                app.DataProcessingPlatformDropDown.ItemsData = availableTrackingProvider;
            end


            %% Attempt to load the user-defined Preference File


            %% Register Components' Static Functions
            app.ProjectRootSelect.AppFigure = app.PlacePrefDataAnalysisUIFigure;


            %% Add listeners for Observable props
            % Set these at the end so that we have the option to initialize
            % the values differently above as needed
            % addlistener(app, 'AppConfigsPath', ...
            %     'PostSet',@app.ConfigFilePathUpdatedFcn ...
            % );


            %% Set default values from config
            startupLoader.Message = "Loading Config File"; drawnow;

            if isempty(ConfigYAMLPath) || ~isfile(ConfigYAMLPath)
                % Attempt to load the config that is relative to the entry file
                [~, callerDir] = app.currentExecutable();
                ConfigYAMLPath = fullfile(callerDir, 'configs.yaml');
                if ~isfile(ConfigYAMLPath)
                    ConfigYAMLPath = fullfile(callerDir, 'configs.yml');
                end
                if ~isfile(ConfigYAMLPath)
                    ConfigYAMLPath = '';
                end
            end
            app.AppConfigsPath = ConfigYAMLPath;

            if isstruct(app.AppConfigs) && ~isempty(fieldnames(app.AppConfigs))
                try
                    startupLoader.Message = "Applying Config Preferences"; drawnow;
                    app.StimuliDirDataSelect.SelectedPaths = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.stimuli_directory);
                    StimuliDirDataSelectPathsSelected(app, []);
                    app.MasterMetadataTableDatasetSelect.SelectedPaths = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.master_metadata_table);
                    MasterMetadataTableDatasetSelectPathsSelected(app, []);
                    app.ProjectRootSelect.SelectedParent = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.project_root);
                    
                    if ~isempty(app.DataProcessingPlatformDropDown.ItemsData)
                        fromConfigKey = {'defaults', 'tracking_platform'};

                        if validator.nestedStructFieldExists(app.AppConfigs, fromConfigKey)
                            fromConfig = getfield(app.AppConfigs, fromConfigKey{:});
                            dropdownIndex = find(strcmpi(fromConfig, app.DataProcessingPlatformDropDown.ItemsData),1);
                            if ~isempty(dropdownIndex)
                                app.DataProcessingPlatformDropDown.Value = app.DataProcessingPlatformDropDown.ItemsData(dropdownIndex);
                                provider = ui.trackingPlatforms.TrackingProvider.initPlatform(char(app.DataProcessingPlatformDropDown.Value));
                                provider.loadConfig(app.AppConfigs);
                                app.TrackingProvider = provider;
                                f = @provider.filterProjectFolder;
                                app.ProjectRootSelect.DropdownItemsFilterFcn = {f};
                            end
                        end
                    end
                    drawnow;
                    app.ProjectRootSelect.refreshDropdown();
                    ProjectRootSelectContentDropDownValueChanged(app, []);
                catch ME
                    warning("PlacePrefDataGUI:startup:FaultyConfigFile", "The config.yaml file might be misconfigured and/or missing required fields. Please double-check before moving on!\n\n%s", getReport(ME));
                    uialert(app.PlacePrefDataAnalysisUIFigure, sprintf("The config.yaml file might be misconfigured and/or missing required fields. Please double-check before moving on!<br><br><article style=""color: #ff0101;"">%s</article>", getReport(ME)), ...
                        "Error Loading Configuration File", "Icon", "error", "Interpreter", "html");
                end
            end


            %% Clear the loader at the end of startup
            startupLoader.Indeterminate = 'off';
            startupLoader.Message = 'Ready!';
            startupLoader.Value = 1;
            SetStatusBar(app);
            close(startupLoader);
        end

        % Close request function: PlacePrefDataAnalysisUIFigure
        function PlacePrefDataAnalysisUIFigureCloseRequest(app, event)
            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Cleaning up...',...
                'Message',"Cleaning up environment before closing...", ...
                "Indeterminate", "on");
            drawnow
            
            % Do clean up tasks of components/modules here before reset PATH


            evalin('base', 'clear(''PlacePreferenceGUI'')' );
            
            % Reset the PATH to how it was before startup
            path(app.StartupPATH);

            close(loader);
            delete(app);
        end

        % Window button motion function: PlacePrefDataAnalysisUIFigure
        function PlacePrefDataAnalysisUIFigureWindowButtonMotion(app, event)
            if isempty(app.MouseMoveCallbackFcnStack) || ~isstruct(app.MouseMoveCallbackFcnStack) || isempty(fieldnames(app.MouseMoveCallbackFcnStack))
                return
            end
            currentFPoints = get(app.Proc2PCIUIFigure, 'CurrentPoint');
            keys = fieldnames(app.MouseMoveCallbackFcnStack);
            for i=1:length(keys)
                thiskey = keys{i};
                thisfcn = app.MouseMoveCallbackFcnStack.(thiskey);
                if isempty(thisfcn)
                    continue
                end

                try
                    argCount = abs(nargin(thisfcn{1}));
                    
                    if argCount == length(thisfcn)
                        % Inject the current position as the last arg if the
                        % function supports it
                        feval(thisfcn{:}, currentFPoints);
                    else
                        % Otherwise, run the callback function as is
                        feval(thisfcn{:})
                    end

                catch ME
                    warning("Failed to run MouseMoveCallbackFcn: %s\n%s", func2str(thisfcn{1}), ME.getReport());
                end
            end
        end

        % Menu selected function: LoadConfigYAMLMenu
        function LoadConfigYAMLMenuSelected(app, event)
            SetStatusBar(app, "Busy", "Awaiting config file selection...");
            [file,location] = uigetfile({'*.yaml;*.yml','YAML Files';}, ...
              'Select Configuration File');
            figure(app.PlacePrefDataAnalysisUIFigure);
            if isequal(file,0)
                SetStatusBar(app);
                return
            end
            
            lastConfigPath = app.AppConfigsPath;
            app.AppConfigsPath = fullfile(location,file);

            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Importing Configs',...
                'Message',"Loading Config File", ...
                "Indeterminate", "on");
            SetStatusBar(app, "Busy", "Loading config file...");
            drawnow

            if isstruct(app.AppConfigs) && ~isempty(fieldnames(app.AppConfigs))
                try
                    loader.Message = "Applying Config Preferences"; drawnow;
                    app.StimuliDirDataSelect.SelectedPaths = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.stimuli_directory);
                    StimuliDirDataSelectPathsSelected(app, []);
                    app.MasterMetadataTableDatasetSelect.SelectedPaths = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.master_metadata_table);
                    MasterMetadataTableDatasetSelectPathsSelected(app, []);
                    app.ProjectRootSelect.SelectedParent = fullfile(app.AppConfigs.CONFIG_ROOT, app.AppConfigs.defaults.project_root);
                    app.ProjectRootSelect.refreshDropdown();
                    ProjectRootSelectContentDropDownValueChanged(app, []);
                catch ME
                    warning("PlacePrefDataGUI:startup:FaultyConfigFile", "The config.yaml file might be misconfigured and/or missing required fields. Please double-check before moving on!\n\n%s", getReport(ME));
                    uialert(app.PlacePrefDataAnalysisUIFigure, sprintf("The config.yaml file might be misconfigured and/or missing required fields. Please double-check before moving on!<br><br><article style=""color: #ff0101;"">%s</article>", getReport(ME)), ...
                        "Error Loading Configuration File", "Icon", "error", "Interpreter", "html");
                end
            else
                app.AppConfigsPath = lastConfigPath;
            end

            provider = ui.trackingPlatforms.TrackingProvider.initPlatform(char(app.DataProcessingPlatformDropDown.Value));
            provider.loadConfig(app.AppConfigs);
            app.TrackingProvider = provider;
            f = @provider.filterProjectFolder;
            app.ProjectRootSelect.DropdownItemsFilterFcn = {f};
            
            close(loader);
            SetStatusBar(app);
        end

        % Menu selected function: RestartAppMenu
        function RestartAppMenuSelected(app, event)
            confirmed = uiconfirm(app.PlacePrefDataAnalysisUIFigure, ...
                "All unsaved changes in this session will be lost forever. Are you sure you want to restart the program?", ...
                "Confirm Restart App", ...
                "Options", ["Restart", "Cancel"], ...
                "CancelOption",2, "DefaultOption",2, ...
                "Icon", "warning");
            if confirmed == "Cancel"
                return
            end

            startscript = fullfile(fileparts(mfilename('fullpath')), '..', 'PlacePrefDataGUI.m');
            PlacePrefDataAnalysisUIFigureCloseRequest(app, event);
            run(startscript);
        end

        % Menu selected function: QuitAppMenu
        function QuitAppMenuSelected(app, event)
            confirmed = uiconfirm(app.PlacePrefDataAnalysisUIFigure, ...
                "All unsaved changes in this session will be lost forever. Are you sure you want to quit the program?", ...
                "Confirm Quit App", ...
                "Options", ["Exit App", "Cancel"], ...
                "CancelOption",2, "DefaultOption",2, ...
                "Icon", "warning");
            if confirmed == "Cancel"
                return
            end
            
            PlacePrefDataAnalysisUIFigureCloseRequest(app, event);
        end

        % Menu selected function: CloseMATLABMenu
        function CloseMATLABMenuSelected(app, event)
            confirmed = uiconfirm(app.PlacePrefDataAnalysisUIFigure, ...
                "The current MATLAB session will close and all programs (not limited to the current app) running in this MATLAB session will be forced to terminate. All unsaved changes will be lost forever. Are you sure you want to quit the program?", ...
                "Confirm Quit MATLAB", ...
                "Options", ["Close MATLAB", "Cancel"], ...
                "CancelOption",2, "DefaultOption",2, ...
                "Icon", "warning");
            if confirmed == "Cancel"
                return
            end

            quit();
        end

        % Menu selected function: ClearCommandWindowMenu
        function ClearCommandWindowMenuSelected(app, event)
            clc;
        end

        % Menu selected function: ToggleAlwaysOnTopMenu
        function ToggleAlwaysOnTopMenuSelected(app, event)
            currentWindowStyle = app.PlacePrefDataAnalysisUIFigure.WindowStyle;
            if currentWindowStyle == "normal"
                app.PlacePrefDataAnalysisUIFigure.WindowStyle = 'alwaysontop';
                app.ToggleAlwaysOnTopMenu.Checked = "on";
            elseif currentWindowStyle == "alwaysontop"
                app.PlacePrefDataAnalysisUIFigure.WindowStyle = 'normal';
                app.ToggleAlwaysOnTopMenu.Checked = "off";
            end
        end

        % Menu selected function: NIDAQmxAudioPlayerMenu
        function NIDAQmxAudioPlayerMenuSelected(app, event)
            web("https://github.com/codynhanpham/nidaq_audioplayer");
        end

        % Menu selected function: AboutthisAppMenu
        function AboutthisAppMenuSelected(app, event)
            % For now, this bit only works for mlapp file, not deployed binary
            % Not sure how to extract the equilvalent there
            if isdeployed
                content = "";
            else
                try
                    app_name = mfilename();
                    %#exclude mlapp.internal.MLAPPMetadataReader
                    mlappMetaDataReader = mlapp.internal.MLAPPMetadataReader(app_name);
                    fileInfo = mlappMetaDataReader.readMLAPPMetadata();
        
                    content = sprintf("<b>Name:</b> %s<br>", fileInfo.Name);
                    if ~isempty(fileInfo.Author)
                        content = sprintf("%s<b>Author:</b> %s<br>", content, fileInfo.Author);
                    end
                    if ~isempty(fileInfo.Version)
                        content = sprintf("%s<b>Version:</b> %s<br>", content, fileInfo.Version);
                    end
                    if ~isempty(fileInfo.MATLABRelease)
                        content = sprintf("%s<b>MATLAB Release:</b> %s<br><br>", content, fileInfo.MATLABRelease);
                    end
        
                    if ~isempty(fileInfo.Summary)
                        content = sprintf("%s<b>Summary:</b> %s<br>", content, fileInfo.Summary);
                    end
                    if ~isempty(fileInfo.Description)
                        content = sprintf("%s<b>Description:</b> %s<br><br>", content, fileInfo.Description);
                    else
                        content = sprintf("%s<br>", content);
                    end
                catch
                    content = "";
                end
            end

            [filename, ~] = app.currentExecutable();
            content = sprintf("%s<b>Standalone Installation:</b> %s<br>", content, string(isdeployed()));
            content = sprintf("%s<b>Main Executable:</b> <p>%s</p><br>", content, string(filename));


            warning("off", "io:stimuli:findAudioMetadataExtractorBin:DefaultInstallationNotFound");
            metbin = io.stimuli.findAudioMetadataExtractorBin(app.AppConfigs);
            metbin = char(metbin);
            warning("on", "io:stimuli:findAudioMetadataExtractorBin:DefaultInstallationNotFound");
    
            warning("off", "io:stimuli:findNiDaqAudioPlayerBin:DefaultInstallationNotFound");
            nibin = io.stimuli.findNiDaqAudioPlayerBin(app.AppConfigs);
            nibin = char(nibin);
            warning("on", "io:stimuli:findNiDaqAudioPlayerBin:DefaultInstallationNotFound");
            binaryPath = '';
            if ~isempty(metbin) && isfile(metbin)
                binaryPath = metbin;
            elseif ~isempty(nibin) && isfile(nibin)
                binaryPath = nibin;
            end
            if isempty(binaryPath) || ~isfile(binaryPath)
                binaryPath = "<p style=""color: #ff0101;"">Missing</p>";
            else
                binaryPath = sprintf("<p>%s</p>", string(binaryPath));
            end
            content = sprintf("%s<b>Media Metadata Extractor:</b> %s", content, binaryPath);

            uialert(app.PlacePrefDataAnalysisUIFigure, content, "About this App", "Interpreter", "html", "Icon", app.PlacePrefDataAnalysisUIFigure.Icon);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            % Hide/show some other tabs when the selection changes
            % if selectedTab.Tag ~= "PlacePrefDataGUI:TabToHideTag"
            %     app.TabToHide.Parent = [];
            % end
        end

        % Callback function: StimuliDirDataSelect
        function StimuliDirDataSelectPathsSelected(app, event)
            selected = app.StimuliDirDataSelect.SelectedPaths;
            selected = char(selected); % this is okay since the component is set to only allow single value
            if ~isempty(selected)
                app.StimuliDirDataSelect.SelectedPaths = cellstr(utils.path.canonicalize(selected));
            end
        end

        % Callback function: MasterMetadataTableDatasetSelect
        function MasterMetadataTableDatasetSelectPathsSelected(app, event)
            selected = app.MasterMetadataTableDatasetSelect.SelectedPaths;
            selected = char(selected); % this is okay since the component is set to only allow single value
            if ~isempty(selected)
                app.MasterMetadataTableDatasetSelect.SelectedPaths = utils.path.canonicalize(selected);
            end

            if ~endsWith(selected, '.xlsx')
                uialert(app.PlacePrefDataAnalysisUIFigure, "Master Metadata Table must be an Excel file with the '.xlsx' extension.", "Invalid Selection", "Icon", "error");
                app.MasterMetadataTableDatasetSelect.SelectedPaths = {};
                return
            end

            app.MasterMetadataTablePath = selected;
        end

        % Image clicked function: TrackingProviderInfoButton
        function TrackingProviderInfoButtonClicked(app, event)
            uialert(app.PlacePrefDataAnalysisUIFigure, ...
                sprintf(['Tracking Provider indicates how the behavioral video data was/would be processed. ', ...
                'A dataset and/or project can be compatible with multiple providers, depending on your set up and needs.<br><br>', ...
                'If your data was collected on a platform that also does the tracking, say <strong>EthoVision</strong>, it would be easiest to get started using that platform. ', ...
                'On the other hand, if you only have raw video data, <strong>DeepLabCut</strong> can do the video processing and generate the tracking data.<br><br>', ...
                'Moreover, if you already have the tracking data from one platform but it is not to satisfactory and/or does not have the detail or resolution you are looking for, ', ...
                'as long as you have the raw video files, you can also re-process the data using a different platform. ', ...
                'A typical use case is to re-analyze datasets collected in EthoVision (which only tracks one/a few bodyparts) with DeepLabCut using dataset-specific fine-tuned detection models ', ...
                'to increase the resolution (as many bodyparts as you''d like) and accuracy.<br><br>', ...
                'If your desired tracking platform is not in this list, you can implement one yourself! ', ...
                'Go to <code style="font-weight:900;">/src/+ui/+trackingPlatforms/</code> and see ', ...
                'the associated <code style="font-weight:900;">README.md</code> file for more information.', ...
                ]), ...
                "Tracking Provider Setup", ...
                "Icon","info", "Interpreter","html")
        end

        % Value changed function: DataProcessingPlatformDropDown
        function DataProcessingPlatformDropDownValueChanged(app, event)
            value = app.DataProcessingPlatformDropDown.Value;
            
            try
                provider = ui.trackingPlatforms.TrackingProvider.initPlatform(char(value));
                provider.loadConfig(app.AppConfigs);
                app.TrackingProvider = provider;
                f = @provider.filterProjectFolder;
                app.ProjectRootSelect.DropdownItemsFilterFcn = {f};

                app.ProjectRootSelect.refreshDropdown();
                ProjectRootSelectContentDropDownValueChanged(app, []);
            catch ME
                app.DataProcessingPlatformDropDown.Value = event.PreviousValue;
                uialert(app.PlacePrefDataAnalysisUIFigure, getReport(ME), "Cannot switch Tracking Provider", "Interpreter","html", "Icon","error");
                rethrow(ME)
            end
        end

        % Callback function: ProjectRootSelect
        function ProjectRootSelectContentDropDownValueChanged(app, event)
            selected = app.ProjectRootSelect.SelectedParent;
            if ~isempty(selected)
                app.ProjectRootSelect.SelectedParent = utils.path.canonicalize(selected);
            end
            
            selected = app.ProjectRootSelect.SelectedContent;

            if isempty(selected), return; end

            switch selected.type
                case "single"
                    app.PopulationAnalysisPanel.Parent = [];
                    app.SingleProjectPanel.Parent = app.DatasetToolsGrid;
                    app.QuickActionsPanel.Parent = app.ProjectSelectionGridLayout;

                    [trialNames, trialsInfo] = app.TrackingProvider.filterTrials(fullfile(selected.dir.folder, selected.dir.name), Options=struct('MetadataTable', app.MasterMetadataTable));
                    app.DataToolsTrialDropDown.Items = trialNames;
                    app.DataToolsTrialDropDown.ItemsData = trialsInfo;
                    DataToolsTrialDropDownValueChanged(app, event);

                    % List of trials
                    if isempty(fieldnames(trialsInfo))
                        app.SplitMultipleArenaToolGrid.Parent = [];
                        app.BatchSplitMultiArenaButton.Enable = "off";
                        app.RunVideoTrackingToolGrid.Parent = [];
                        app.BatchRunVideoTrackingButton.Enable = "off";
                    else
                        if sum([trialsInfo.multipleArena]) == 0
                            app.SplitMultipleArenaToolGrid.Parent = [];
                            app.BatchSplitMultiArenaButton.Enable = "off";
                            % Only allow batch video tracking when all videos are pre-processed
                            if sum(cellfun(@isempty, {trialsInfo.data})) == 0
                                app.RunVideoTrackingToolGrid.Parent = [];
                                app.BatchRunVideoTrackingButton.Enable = "off";
                            else
                                app.RunVideoTrackingToolGrid.Parent = app.QuickActionGrid;
                                app.BatchRunVideoTrackingButton.Enable = "on";
                            end
                        else
                            app.SplitMultipleArenaToolGrid.Parent = app.QuickActionGrid;
                            app.BatchSplitMultiArenaButton.Enable = "on";
                            app.RunVideoTrackingToolGrid.Parent = [];
                            app.BatchRunVideoTrackingButton.Enable = "off";
                        end
                    end

                case "population"
                    app.SingleProjectPanel.Parent = [];
                    app.PopulationAnalysisPanel.Parent = app.DatasetToolsGrid;
                    app.QuickActionsPanel.Parent = [];

                    loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Population Analysis Utilities',...
                        'Message',"Scanning Datasets for Trials...", ...
                        "Indeterminate", "on");
                    drawnow
                    cleanupObj = onCleanup(@() close(loader));
                    
                    ui.updateBatchDataTree(app.BatchDataSelectRootNode, selected);
            end
        end

        % Button pushed function: OpenFolderInFileManager
        function OpenFolderInFileManagerButtonPushed(app, event)
            selected = app.ProjectRootSelect.SelectedParent;
            if ~isempty(selected)
                app.ProjectRootSelect.SelectedParent = utils.path.canonicalize(selected);
            end
            
            selected = app.ProjectRootSelect.SelectedContent;

            if isempty(selected), return; end
            if selected.type == "population", return; end
            utils.io.systemFileManager(fullfile(selected.dir.folder, selected.dir.name));


        end

        % Button pushed function: BatchSplitMultiArenaButton
        function BatchSplitMultiArenaButtonPushed(app, event)
            % Parent folder
            selected = app.ProjectRootSelect.SelectedParent;
            if ~isempty(selected)
                app.ProjectRootSelect.SelectedParent = utils.path.canonicalize(selected);
            end
            
            selected = app.ProjectRootSelect.SelectedContent;
            if isempty(selected), return; end

            % List of trials
            trials = app.DataToolsTrialDropDown.ItemsData;
            if isempty(trials)
                trials = app.DataToolsTrialDropDown.Items;
            end
            if isempty(trials)
                return
            end

            SetStatusBar(app, "Busy", sprintf("Looking for multiple-arena trials..."));
            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Batch Splitting Mutiple-Arena Trials',...
                'Message',sprintf("Looking for trials that includes multiple arenas in \n%s", selected.dir.name), ...
                "Indeterminate", "on");
            drawnow;

            
            multiarenatrials = trials([trials.multipleArena]);
            loader.Indeterminate = "off";
            loader.Value = 0.01;
            loader.ShowPercentage = "on";
            SetStatusBar(app, "Busy", sprintf("Running pre-processing..."));
            for i=1:length(multiarenatrials)
                trial = multiarenatrials(i);
                [~,n,~] = fileparts(trial.media);
                loader.Message = sprintf("[%s]\nRunning pre-processing for: %s", selected.dir.name, n);
                loader.Value = (i)/(length(multiarenatrials)+1);
                SetStatusBar(app, "Busy", sprintf("Running pre-processing for: %s ...", n));
                drawnow

                app.TrackingProvider.preprocess(trial.data, app.MasterMetadataTable);
            end

            SetStatusBar(app, "Busy", sprintf("Updating trial list..."));
            RefreshTrialListButtonPushed(app, []);

            close(loader);
            SetStatusBar(app);
        end

        % Button pushed function: BatchRunVideoTrackingButton
        function BatchRunVideoTrackingButtonPushed(app, event)
            % Parent folder
            selected = app.ProjectRootSelect.SelectedParent;
            if ~isempty(selected)
                app.ProjectRootSelect.SelectedParent = utils.path.canonicalize(selected);
            end
            
            selected = app.ProjectRootSelect.SelectedContent;
            if isempty(selected), return; end

            % List of trials
            trials = app.DataToolsTrialDropDown.ItemsData;
            if isempty(trials)
                trials = app.DataToolsTrialDropDown.Items;
            end
            if isempty(trials)
                return
            end

            SetStatusBar(app, "Busy", sprintf("Looking trials with missing tracking..."));
            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Trials Video Tracking',...
                'Message',sprintf("Looking for trials that are missing tracking data in \n%s", selected.dir.name), ...
                "Indeterminate", "on");
            drawnow;
            tobetracked = trials(cellfun(@isempty, {trials.data}));
            tobetracked = {tobetracked.media};
            SetStatusBar(app, "Busy", sprintf("Running batch video tracking..."));
            loader.Message = "Running batch video tracking. This will take a while..."; drawnow;
            [status, outputdir] = app.TrackingProvider.runTracking(tobetracked); %#ok<ASGLU>
            if ~status
                uialert(app.PlacePrefDataAnalysisUIFigure, "Something went wrong and the platform tracking pipeline returned with a non-successful exit code. The Command Window output may reveal more information about this.", 'Something went wrong!!!');
            end

            loader.Message = "Updating trial list...";
            SetStatusBar(app, "Busy", sprintf("Updating trial list..."));
            RefreshTrialListButtonPushed(app, []);

            close(loader);
            SetStatusBar(app);
        end

        % Value changed function: DataToolsTrialDropDown
        function DataToolsTrialDropDownValueChanged(app, event)
            value = app.DataToolsTrialDropDown.Value;
            if isempty(value) || ~isstruct(value) || isempty(fieldnames(value))
                return
            end

            % Find the index of the selected trial
            trialIndex = find(arrayfun(@(x) isequal(x, value), app.DataToolsTrialDropDown.ItemsData), 1);
            nTrials = length(app.DataToolsTrialDropDown.ItemsData);
            app.PreviousTrialButton.UserData.currentTrialIndex = trialIndex;
            app.PreviousTrialButton.UserData.nTrials = nTrials;
            app.NextTrialButton.UserData.currentTrialIndex = trialIndex;
            app.NextTrialButton.UserData.nTrials = nTrials;
            
            app.PreviousTrialButton.Enable = trialIndex > 1;
            app.NextTrialButton.Enable = trialIndex < nTrials;

            % Default the processing options to hidden
            app.SingleTrialDisplayGridLayout.Parent = [];
            app.SingleTrialRunTrackingRequiredGridLayout.Parent = [];

            if value.multipleArena
                app.SingleTrialDisplayGridLayout.Parent = [];
                app.SingleTrialPreprocessingRequiredGridLayout.Parent = app.SingleProjectGridLayout;
                app.DataToolsTrialVideoPlayButton.Enable = 'off';
                app.DataToolsTrialRawDataSheetButton.Enable = 'off';
                app.StimuliDuringTrialLabel.Text = "!!! Preprocessing Required !!!";
                return
            else
                app.SingleTrialDisplayGridLayout.Parent = app.SingleProjectGridLayout;
                app.SingleTrialPreprocessingRequiredGridLayout.Parent = [];
                app.DataToolsTrialVideoPlayButton.Enable = 'on';
                app.DataToolsTrialRawDataSheetButton.Enable = 'on';
            end

            if isempty(value.data)
                app.SingleTrialDisplayGridLayout.Parent = [];
                app.SingleTrialRunTrackingRequiredGridLayout.Parent = app.SingleProjectGridLayout;
                app.DataToolsTrialVideoPlayButton.Enable = 'off';
                app.DataToolsTrialRawDataSheetButton.Enable = 'off';
                app.StimuliDuringTrialLabel.Text = "!!! Video Tracking Required !!!";
                return
            else
                app.SingleTrialDisplayGridLayout.Parent = app.SingleProjectGridLayout;
                app.SingleTrialRunTrackingRequiredGridLayout.Parent = [];
                app.DataToolsTrialVideoPlayButton.Enable = 'on';
                app.DataToolsTrialRawDataSheetButton.Enable = 'on';
            end


            app.DatasetToolsPanel.BorderColor = semanticColor(app, "busy");
            SetStatusBar(app, "Busy", sprintf("Loading trial data: %s", string(utils.path.formatPathParts(value.data))));
            app.DataToolsTrialDropDown.Enable = "off";
            app.PreviousTrialButton.Enable = "off";
            app.NextTrialButton.Enable = "off";
            drawnow;

            % Load EthoVision data
            [header, ~, ~] = app.TrackingProvider.loadTrackingData(value.data, Options=struct('HeaderOnly', true, 'MetadataTable', app.MasterMetadataTable));
            
            % Extract trial info more efficiently
            trialName = header("Trial name");
            trialParts = split(trialName, ' ');
            trialNumber = str2double(strtrim(trialParts{end}));
            experimentName = header("Experiment");
            arenaName = header("Arena name");

            % Extract metadata parameters if available from the matching row in master metadata
            masterMetadata = app.MasterMetadataTable;
            if ~isempty(masterMetadata)
                trialMask = (masterMetadata.ETHOVISION_TRIAL == trialNumber) & ...
                    (masterMetadata.ETHOVISION_FILE == experimentName) & ...
                    (masterMetadata.ETHOVISION_ARENA == arenaName);
                trialRowIdx = find(trialMask, 1);
        
                % https://www.mathworks.com/matlabcentral/answers/96617-how-can-i-use-nargin-nargout-to-determine-the-number-of-input-output-arguments-of-an-object-method
                % When updating the main class and/or the method, MAKE SURE to update the line below!
                numout = nargout("PlacePrefDataGUI_main>PlacePrefDataGUI_main.extractTrialMetadataFromMasterTable");
                argout = utils.allargout(@app.extractTrialMetadataFromMasterTable, masterMetadata, trialRowIdx, value.data, header, NumOutput=numout);
                updateUIMetadata(app, argout{:});
            end

            app.DatasetToolsPanel.BorderColor = [0.49,0.49,0.49];
            SetStatusBar(app);
            app.DataToolsTrialDropDown.Enable = "on";
            app.PreviousTrialButton.Enable = trialIndex > 1;
            app.NextTrialButton.Enable = trialIndex < nTrials;
            drawnow;
        end

        % Button pushed function: RefreshTrialListButton
        function RefreshTrialListButtonPushed(app, event)
            ProjectRootSelectContentDropDownValueChanged(app, event);
        end

        % Button pushed function: PreviousTrialButton
        function PreviousTrialButtonPushed(app, event)
            currentT = app.PreviousTrialButton.UserData.currentTrialIndex;
            if currentT > 1
                targetT = currentT -1;
            end
            app.DataToolsTrialDropDown.Value = app.DataToolsTrialDropDown.ItemsData(targetT);
            DataToolsTrialDropDownValueChanged(app, event);
        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            currentT = app.PreviousTrialButton.UserData.currentTrialIndex;
            nTrials = app.PreviousTrialButton.UserData.nTrials;
            if currentT < nTrials
                targetT = currentT +1;
            end
            app.DataToolsTrialDropDown.Value = app.DataToolsTrialDropDown.ItemsData(targetT);
            DataToolsTrialDropDownValueChanged(app, event);
        end

        % Button pushed function: DataToolsTrialVideoPlayButton
        function DataToolsTrialVideoPlayButtonPushed(app, event)
            trial = app.DataToolsTrialDropDown.Value;

            if isempty(trial) || ~isstruct(trial), return; end

            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Launching Video Player...',...
                'Message',"Initializing tracked video player...", ...
                "Indeterminate", "on");
            drawnow;

            f = graphics.trialMediaPlayer("VideoFile", trial.media, "TrackingDataFile", trial.data, "TrackingProvider", app.TrackingProvider);
            close(loader);
        end

        % Button pushed function: DataToolsTrialRawDataSheetButton
        function DataToolsTrialRawDataSheetButtonPushed(app, event)
            trial = app.DataToolsTrialDropDown.Value;
            if isempty(trial) || ~isstruct(trial), return; end
            if (ispc)
                winopen(trial.data);
            elseif ismac
                cmdToExecute = ['open ' trial.data];
                [status, path] = system(cmdToExecute); %#ok<ASGLU>
            else
                cmdToExecute = ['xdg-open ' trial.data];
                [status, path] = system(cmdToExecute); %#ok<ASGLU>
            end
        end

        % Button pushed function: SplitMultipleArenaTrialButton
        function SplitMultipleArenaTrialButtonPushed(app, event)
            trial = app.DataToolsTrialDropDown.Value;
            if isempty(trial) || ~isstruct(trial), return; end
            
            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Multiple-Arena Pre-processing',...
                'Message',"Running pre-processing. This will take a while...", ...
                "Indeterminate", "on", "ShowPercentage","on");
            SetStatusBar(app, "Busy", "Multiple-Arena Pre-processing...");
            drawnow
            
            results = app.TrackingProvider.preprocess(trial.data, app.MasterMetadataTable, Options=struct('ProgressDialogHandle', loader));
            RefreshTrialListButtonPushed(app, [])

            close(loader)
            SetStatusBar(app);
            drawnow;
        end

        % Button pushed function: TrackVideoButton
        function TrackVideoButtonPushed(app, event)
            trial = app.DataToolsTrialDropDown.Value;
            if isempty(trial) || ~isstruct(trial), return; end
            
            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Video Tracking',...
                'Message',"Running video tracking. This will take a while...", ...
                "Indeterminate", "on", "ShowPercentage","on");
            SetStatusBar(app, "Busy", "Video Tracking...");
            drawnow
            
            [status, outputdir] = app.TrackingProvider.runTracking(trial.media);
            if ~status
                uialert(app.PlacePrefDataAnalysisUIFigure, "Something went wrong and the platform tracking pipeline returned with a non-successful exit code. The Command Window output may reveal more information about this.", 'Something went wrong!!!');
            end
            RefreshTrialListButtonPushed(app, [])

            close(loader)
            SetStatusBar(app);
            drawnow;
        end

        % Value changed function: DateofBirthDatePicker
        function DateofBirthDatePickerValueChanged(app, event)
            % This is only to prevent the bug where even the datepicker is set with Editable = 'off'
            % Changing the value using the picker UI still update the value
            % In future MATLAB version, this might become unnecessary
            app.DateofBirthDatePicker.Value = event.PreviousValue;
        end

        % Button pushed function: PlacePreferencecenterpointButton
        function PlacePreferencecenterpointButtonPushed(app, event)
            trial = app.DataToolsTrialDropDown.Value;
            if isempty(trial) || ~isstruct(trial), return; end

            stimdir = app.StimuliDirDataSelect.SelectedPaths;
            stimdir = char(stimdir); % this is okay since the component is set to only allow single value
            if isempty(stimdir), return; end

            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Crunching the data...',...
                'Message',"Processing tracked positions and rendering plots...", ...
                "Indeterminate", "on");
            drawnow;
            graphics.trialPlacePref(trial.data, stimdir, app.MasterMetadataTable, "Config", app.AppConfigs);
            close(loader);
        end

        % Button pushed function: SubjectsDemographicsButton
        function SubjectsDemographicsButtonPushed(app, event)
            checkedNodes = app.BatchDataSelectTree.CheckedNodes;

            loader = uiprogressdlg(app.PlacePrefDataAnalysisUIFigure,'Title','Crunching the data...',...
                'Message',"Processing tracked positions and rendering plots...", ...
                "Indeterminate", "on");
            drawnow;

            % Only Trial Nodes have data of struct
            trialDataIndex = arrayfun(@(x) isstruct(x.NodeData), checkedNodes, 'UniformOutput', true);
            trialNodes = checkedNodes(trialDataIndex);
            trialsInfo = arrayfun(@(x) x.NodeData, trialNodes, 'UniformOutput', true);
            
            isinMetadataTable = io.metadata.includesTrials(app.MasterMetadataTable, trialsInfo);
            trialsInfo = trialsInfo(isinMetadataTable);

            subsetMetadata = io.metadata.subsetByTrials(app.MasterMetadataTable, trialsInfo);

            if height(subsetMetadata) == 0
                close(loader);
                return
            end

            graphics.populationDemographics(subsetMetadata);
            close(loader);
        end

        % Button pushed function: ExperimentOverviewButton
        function ExperimentOverviewButtonPushed(app, event)
            
        end

        % Button pushed function: PlacePreferencePopulationAnalysisButton
        function PlacePreferencePopulationAnalysisButtonPushed(app, event)
            SetStatusBar(app, "Busy", "Running PlacePref Population Analysis...");
            drawnow;

            checkedNodes = app.BatchDataSelectTree.CheckedNodes;
            checkedTrials = checkedNodes(arrayfun(@(x) isstruct(x.NodeData), checkedNodes)); % only the most inner child nodes are trialInfo structs
            trials = arrayfun(@(x) x.NodeData, checkedTrials);

            % Crunching the summary data will take a long while, so only make summary if
            % the input data is updated. Note that this does NOT check the content within each input files,
            % only the list of files that are included (except for MasterMetadataTable).
            if isempty(app.PopulationSummaryTableInputHash) || ~strcmp(app.PopulationCurrentlySelectedInputsHash, app.PopulationSummaryTableInputHash) ...
                    || length(trials) ~= height(app.PopulationSummaryTable)
                stimdir = app.StimuliDirDataSelect.SelectedPaths;
                stimdir = char(stimdir); % this is okay since the component is set to only allow single value
    
                app.PopulationSummaryTableInputHash = app.PopulationCurrentlySelectedInputsHash;
                summary = population.stats.populationSummary(trials, stimdir, app.MasterMetadataTable, "Config", app.AppConfigs, "UIFigure", app.Figure);
                app.PopulationSummaryTable = summary;
            end

            normtable = population.stats.normalizeSummaryTable(app.PopulationSummaryTable);
            f = graphics.populationPlacePref(normtable, app.PopulationSummaryTable, "MainApp", app);

            SetStatusBar(app);
            drawnow;
        end

        % Button pushed function: ActivitybyStimuliPopulationAnalysisButton
        function ActivitybyStimuliPopulationAnalysisButtonPushed(app, event)
            SetStatusBar(app, "Busy", "Running [Activity-by-Stims] Analysis...");
            drawnow;

            checkedNodes = app.BatchDataSelectTree.CheckedNodes;
            checkedTrials = checkedNodes(arrayfun(@(x) isstruct(x.NodeData), checkedNodes)); % only the most inner child nodes are trialInfo structs
            trials = arrayfun(@(x) x.NodeData, checkedTrials);

            % % Crunching the summary data will take a long while, so only make summary if
            % % the input data is updated. Note that this does NOT check the content within each input files,
            % % only the list of files that are included (except for MasterMetadataTable).
            % if isempty(app.PopulationSummaryTableInputHash) || ~strcmp(app.PopulationCurrentlySelectedInputsHash, app.PopulationSummaryTableInputHash) ...
            %         || length(trials) ~= height(app.PopulationSummaryTable)
            %     stimdir = app.StimuliDirDataSelect.SelectedPaths;
            %     stimdir = char(stimdir); % this is okay since the component is set to only allow single value
            % 
            %     app.PopulationSummaryTableInputHash = app.PopulationCurrentlySelectedInputsHash;
            %     summary = population.stats.populationSummary(trials, stimdir, app.MasterMetadataTable, "Config", app.AppConfigs, "UIFigure", app.Figure);
            %     app.PopulationSummaryTable = summary;
            % end

            stimdir = app.StimuliDirDataSelect.SelectedPaths;
            stimdir = char(stimdir); % this is okay since the component is set to only allow single value

            summary = population.stats.populationPositionByStim(trials, stimdir, app.MasterMetadataTable, "Config", app.AppConfigs, "UIFigure", app.Figure);

            SetStatusBar(app);
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlacePrefDataAnalysisUIFigure = uifigure('Visible', 'off');
            app.PlacePrefDataAnalysisUIFigure.Name = 'PlacePref Data Analysis';
            app.PlacePrefDataAnalysisUIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'flip-horizontal.png');
            app.PlacePrefDataAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @PlacePrefDataAnalysisUIFigureCloseRequest, true);
            app.PlacePrefDataAnalysisUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @PlacePrefDataAnalysisUIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.PlacePrefDataAnalysisUIFigure);
            app.FileMenu.Text = '  File  ';
            app.LoadConfigYAMLMenu = uimenu(app.FileMenu);
            app.LoadConfigYAMLMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigYAMLMenuSelected, true);
            app.LoadConfigYAMLMenu.Accelerator = 'O';
            app.LoadConfigYAMLMenu.Text = 'Load Config YAML...';
            app.RestartAppMenu = uimenu(app.FileMenu);
            app.RestartAppMenu.MenuSelectedFcn = createCallbackFcn(app, @RestartAppMenuSelected, true);
            app.RestartAppMenu.Separator = 'on';
            app.RestartAppMenu.Text = 'Restart App';
            app.QuitAppMenu = uimenu(app.FileMenu);
            app.QuitAppMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitAppMenuSelected, true);
            app.QuitAppMenu.Text = 'Quit App';
            app.CloseMATLABMenu = uimenu(app.FileMenu);
            app.CloseMATLABMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMATLABMenuSelected, true);
            app.CloseMATLABMenu.Separator = 'on';
            app.CloseMATLABMenu.Text = 'Close MATLAB';
            app.ViewMenu = uimenu(app.PlacePrefDataAnalysisUIFigure);
            app.ViewMenu.Text = '  View  ';
            app.ClearCommandWindowMenu = uimenu(app.ViewMenu);
            app.ClearCommandWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCommandWindowMenuSelected, true);
            app.ClearCommandWindowMenu.Accelerator = 'L';
            app.ClearCommandWindowMenu.Text = 'Clear Command Window';
            app.ToggleAlwaysOnTopMenu = uimenu(app.ViewMenu);
            app.ToggleAlwaysOnTopMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleAlwaysOnTopMenuSelected, true);
            app.ToggleAlwaysOnTopMenu.Separator = 'on';
            app.ToggleAlwaysOnTopMenu.Text = 'Toggle Always On Top';
            app.HelpMenu = uimenu(app.PlacePrefDataAnalysisUIFigure);
            app.HelpMenu.Text = '  Help  ';
            app.RelatedProgramsMenu = uimenu(app.HelpMenu);
            app.RelatedProgramsMenu.Text = 'Related Programs';
            app.NIDAQmxAudioPlayerMenu = uimenu(app.RelatedProgramsMenu);
            app.NIDAQmxAudioPlayerMenu.MenuSelectedFcn = createCallbackFcn(app, @NIDAQmxAudioPlayerMenuSelected, true);
            app.NIDAQmxAudioPlayerMenu.Tooltip = {'The software for playing audio stimluli and sending triggers.'; ''; 'It can also be use to create a composite audio file from a playlist with sub-milisecond-accuracy chapter timestamps.'; ''; '(Click to view on GitHub)'};
            app.NIDAQmxAudioPlayerMenu.Text = 'NI-DAQmx Audio Player';
            app.AboutthisAppMenu = uimenu(app.HelpMenu);
            app.AboutthisAppMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutthisAppMenuSelected, true);
            app.AboutthisAppMenu.Separator = 'on';
            app.AboutthisAppMenu.Text = 'About this App...';
            app.GridLayout = uigridlayout(app.PlacePrefDataAnalysisUIFigure);
            app.AppStatusBarPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.AppStatusBarPanel);
            app.AppStatusBarState = uilabel(app.GridLayout2);
            app.AppStatusBarState.Text = 'Ready';
            app.AppStatusBarDesc = uilabel(app.GridLayout2);
            app.AppStatusBarDesc.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataSelectionTab = uitab(app.TabGroup);
            app.DataSelectionTab.Title = 'Data Selection';
            app.GridLayout3 = uigridlayout(app.DataSelectionTab);
            app.StimuliDirDataSelect = PathSelector(app.GridLayout3);
            app.StimuliDirDataSelect.BorderSize = 2;
            app.StimuliDirDataSelect.PanelTitle = 'Stimuli Playlist Location';
            app.StimuliDirDataSelect.PanelTitleFontSize = 16;
            app.StimuliDirDataSelect.PanelTitlePosition = 'centertop';
            app.StimuliDirDataSelect.Placeholder = 'Path to a directory containing some audio files. This will recursively search up to 3 levels deep for matching audio files.';
            app.StimuliDirDataSelect.SelectMultiple = false;
            app.StimuliDirDataSelect.SelectType = 'folder';
            app.StimuliDirDataSelect.PathsSelectedFcn = createCallbackFcn(app, @StimuliDirDataSelectPathsSelected, true);
            app.MasterMetadataTableDatasetSelect = PathSelector(app.GridLayout3);
            app.MasterMetadataTableDatasetSelect.BorderSize = 2;
            app.MasterMetadataTableDatasetSelect.PanelTitle = 'Master Metadata Table';
            app.MasterMetadataTableDatasetSelect.PanelTitleFontSize = 16;
            app.MasterMetadataTableDatasetSelect.PanelTitlePosition = 'centertop';
            app.MasterMetadataTableDatasetSelect.Placeholder = 'Path to an *.xlsx file';
            app.MasterMetadataTableDatasetSelect.SelectMultiple = false;
            app.MasterMetadataTableDatasetSelect.PathsSelectedFcn = createCallbackFcn(app, @MasterMetadataTableDatasetSelectPathsSelected, true);
            app.DatasetToolsPanel = uipanel(app.GridLayout3);
            app.DatasetToolsPanel.Title = '🛠️  Dataset Tools';
            app.DatasetToolsGrid = uigridlayout(app.DatasetToolsPanel);
            app.SingleProjectPanel = uipanel(app.DatasetToolsGrid);
            app.SingleProjectGridLayout = uigridlayout(app.SingleProjectPanel);
            app.GridLayout6 = uigridlayout(app.SingleProjectGridLayout);
            app.DataToolsTrialVideoPlayButton = uibutton(app.GridLayout6, 'push');
            app.DataToolsTrialVideoPlayButton.ButtonPushedFcn = createCallbackFcn(app, @DataToolsTrialVideoPlayButtonPushed, true);
            app.DataToolsTrialVideoPlayButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'play.svg');
            app.DataToolsTrialVideoPlayButton.Tooltip = {'Play the recorded video. If tracking information is available, the tracked positions is overlayed during playback'};
            app.DataToolsTrialVideoPlayButton.Text = 'Play Tracked Video';
            app.DataToolsTrialRawDataSheetButton = uibutton(app.GridLayout6, 'push');
            app.DataToolsTrialRawDataSheetButton.ButtonPushedFcn = createCallbackFcn(app, @DataToolsTrialRawDataSheetButtonPushed, true);
            app.DataToolsTrialRawDataSheetButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'file-spreadsheet.svg');
            app.DataToolsTrialRawDataSheetButton.Tooltip = {'Open the raw data table (tracking data) in your default spreadsheet viewer'};
            app.DataToolsTrialRawDataSheetButton.Text = 'View Raw Data Table';
            app.SingleTrialDisplayGridLayout = uigridlayout(app.SingleProjectGridLayout);
            app.GridLayout7_2 = uigridlayout(app.SingleTrialDisplayGridLayout);
            app.PlacePreferencecenterpointButton = uibutton(app.GridLayout7_2, 'push');
            app.PlacePreferencecenterpointButton.ButtonPushedFcn = createCallbackFcn(app, @PlacePreferencecenterpointButtonPushed, true);
            app.PlacePreferencecenterpointButton.Text = 'Place Preference (center-point)';
            app.MetadataPanel = uipanel(app.SingleTrialDisplayGridLayout);
            app.MetadataPanel.Tooltip = {'The metadata of this trial, as specified in the Master Metadata Table'; ''; 'To edit the values, update them directly in the Master Metadata Table and refresh this trial dropdown'};
            app.MetadataPanel.Title = 'Metadata';
            app.GridLayout7 = uigridlayout(app.MetadataPanel);
            app.CageCodeEditFieldLabel = uilabel(app.GridLayout7);
            app.CageCodeEditFieldLabel.Text = 'Cage Code';
            app.CageCodeEditField = uieditfield(app.GridLayout7, 'text');
            app.CageCodeEditField.Editable = 'off';
            app.CageCodeEditField.Tooltip = {'The cage code of this animal'};
            app.AnimalIDEditFieldLabel = uilabel(app.GridLayout7);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.GridLayout7, 'text');
            app.AnimalIDEditField.Editable = 'off';
            app.AnimalIDEditField.Tooltip = {'The unique animal ID, either absolute or relative to its Cage Code'};
            app.DateofBirthDatePickerLabel = uilabel(app.GridLayout7);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirthDatePicker = uidatepicker(app.GridLayout7);
            app.DateofBirthDatePicker.Editable = 'off';
            app.DateofBirthDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateofBirthDatePickerValueChanged, true);
            app.DateofBirthDatePicker.Tooltip = {'The date of birth of this animal'};
            app.AgeEditFieldLabel = uilabel(app.GridLayout7);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.AgeEditField.RoundFractionalValues = 'on';
            app.AgeEditField.ValueDisplayFormat = '%.0f';
            app.AgeEditField.Editable = 'off';
            app.AgeEditField.Tooltip = {'The postnatal age of this animal at experiment'};
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.SexEditFieldLabel = uilabel(app.GridLayout8);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.GridLayout8, 'text');
            app.SexEditField.Editable = 'off';
            app.SexEditField.Tooltip = {'The biological sex of this animal'};
            app.StrainEditFieldLabel = uilabel(app.GridLayout8);
            app.StrainEditFieldLabel.Text = 'Strain';
            app.StrainEditField = uieditfield(app.GridLayout8, 'text');
            app.StrainEditField.Editable = 'off';
            app.StrainEditField.Tooltip = {'The animal''s strain or transgenic line'};
            app.GenotypeEditFieldLabel = uilabel(app.GridLayout8);
            app.GenotypeEditFieldLabel.Text = 'Genotype';
            app.GenotypeEditField = uieditfield(app.GridLayout8, 'text');
            app.GenotypeEditField.Editable = 'off';
            app.GenotypeEditField.Tooltip = {'The genotype of this animal'};
            app.SourceHistoryEditFieldLabel = uilabel(app.GridLayout7);
            app.SourceHistoryEditFieldLabel.Text = 'Source/History';
            app.SourceHistoryEditField = uieditfield(app.GridLayout7, 'text');
            app.SourceHistoryEditField.Editable = 'off';
            app.SourceHistoryEditField.Tooltip = {'The "parent" cage code or tracking information'};
            app.GridLayout8_2 = uigridlayout(app.GridLayout7);
            app.SpeakerFlippedEditFieldLabel = uilabel(app.GridLayout8_2);
            app.SpeakerFlippedEditFieldLabel.Text = 'Speaker Flipped';
            app.SpeakerFlippedEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.SpeakerFlippedEditField.Limits = [0 1];
            app.SpeakerFlippedEditField.RoundFractionalValues = 'on';
            app.SpeakerFlippedEditField.ValueDisplayFormat = '%.0f';
            app.SpeakerFlippedEditField.Editable = 'off';
            app.SpeakerFlippedEditField.Tooltip = {'Whether stereo audio stimuli was flipped during this trial'; ''; 'By default, for stereo audio, Channel 1 is Left and Channel 2 is Right. If the physical setup or stimulus track is flipped, indicate that here.'; ''; '0 == Normal'; '1 == Flipped'};
            app.HabituationsEditFieldLabel = uilabel(app.GridLayout8_2);
            app.HabituationsEditFieldLabel.Text = 'Habituation (s)';
            app.HabituationsEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.HabituationsEditField.Limits = [0 Inf];
            app.HabituationsEditField.ValueDisplayFormat = '%.3f';
            app.HabituationsEditField.Editable = 'off';
            app.HabituationsEditField.Tooltip = {'The habituation or pre-stimulus period, in seconds'; ''; 'This is the amount of time between the start of video and the start of stimulus protocol'};
            app.StimStartFrameEditFieldLabel = uilabel(app.GridLayout8_2);
            app.StimStartFrameEditFieldLabel.Text = 'Stim Start Frame';
            app.StimStartFrameEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.StimStartFrameEditField.Limits = [1 Inf];
            app.StimStartFrameEditField.RoundFractionalValues = 'on';
            app.StimStartFrameEditField.ValueDisplayFormat = '%.0f';
            app.StimStartFrameEditField.Editable = 'off';
            app.StimStartFrameEditField.Tooltip = {'The exact frame in the video where the stimulus protocol starts'; ''; 'This value, by default, is equals the habituation period duration multiplies by the video frame rate.  However, in practice, the actual start frame might be delayed by a few frames due to hardware processing speed and/or inter-processes/hardware communication/signaling delays. If the real start frame is known (for example, via a synchronous signal in the video), set it here to overwrite the default time-based value.'};
            app.StimStartFrameEditField.Value = 1;
            app.SingleTrialPreprocessingRequiredGridLayout = uigridlayout(app.SingleProjectGridLayout);
            app.PreprocessingRequiredPanel = uipanel(app.SingleTrialPreprocessingRequiredGridLayout);
            app.PreprocessingRequiredPanel.Title = 'Preprocessing Required';
            app.GridLayout14 = uigridlayout(app.PreprocessingRequiredPanel);
            app.MultipleArenaPreprocessingNotice = uilabel(app.GridLayout14);
            app.MultipleArenaPreprocessingNotice.Interpreter = 'html';
            app.MultipleArenaPreprocessingNotice.Text = {'This EthoVision trial includes multiple arenas. For improved processing speed and more streamlined population analysis, some preprocessing is required before you can proceed.'; ''; 'The preprocessing step splits the recorded video file and tracking data (according to the Master Metadata Table and your Configuration) such that each arena in the trial has its own video and tracking file. The resulting files will be saved to <code style="font-weight: bold;">Export Files/</code> and <code style="font-weight: bold;">Media Files/</code> accordingly, with an <code style="font-weight: bold; color:#6366f1"> @ {{Arena name}}</code> suffix. The original files will be moved to their corresponding <code style="font-weight: bold;">./raw</code> subfolder as backup.'};
            app.SplitMultipleArenaTrialButton = uibutton(app.GridLayout14, 'push');
            app.SplitMultipleArenaTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SplitMultipleArenaTrialButtonPushed, true);
            app.SplitMultipleArenaTrialButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'squares-intersect.svg');
            app.SplitMultipleArenaTrialButton.Text = 'Split Multiple Arena Trial';
            app.GridLayout16 = uigridlayout(app.SingleProjectGridLayout);
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.DataToolsTrialDropDownLabel = uilabel(app.GridLayout17);
            app.DataToolsTrialDropDownLabel.Text = 'Trial # ';
            app.DataToolsTrialDropDown = uidropdown(app.GridLayout17);
            app.DataToolsTrialDropDown.Items = {};
            app.DataToolsTrialDropDown.ValueChangedFcn = createCallbackFcn(app, @DataToolsTrialDropDownValueChanged, true);
            app.DataToolsTrialDropDown.Placeholder = 'EthoVision Trial Number';
            app.DataToolsTrialDropDown.Value = {};
            app.RefreshTrialListButton = uibutton(app.GridLayout17, 'push');
            app.RefreshTrialListButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshTrialListButtonPushed, true);
            app.RefreshTrialListButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'refresh-ccw.svg');
            app.RefreshTrialListButton.Tooltip = {'Refresh the drop down menu and selected trial content'; ''; 'Useful when more trials are added to the same dataset or the associated data of the currently selected trial is updated while this app is running'};
            app.RefreshTrialListButton.Text = '';
            app.GridLayout18 = uigridlayout(app.GridLayout16);
            app.PreviousTrialButton = uibutton(app.GridLayout18, 'push');
            app.PreviousTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrialButtonPushed, true);
            app.PreviousTrialButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'chevron-left.svg');
            app.PreviousTrialButton.Enable = 'off';
            app.PreviousTrialButton.Text = 'Previous Trial';
            app.NextTrialButton = uibutton(app.GridLayout18, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'chevron-right.svg');
            app.NextTrialButton.Enable = 'off';
            app.NextTrialButton.Text = 'Next Trial';
            app.StimuliDuringTrialLabel = uilabel(app.SingleProjectGridLayout);
            app.StimuliDuringTrialLabel.Tooltip = {'The stimulus protocol played during this trial, as specified in Master Metadata Table'};
            app.StimuliDuringTrialLabel.Text = 'Stimuli During Trial';
            app.SingleTrialRunTrackingRequiredGridLayout = uigridlayout(app.SingleProjectGridLayout);
            app.RunTrackingPanel = uipanel(app.SingleTrialRunTrackingRequiredGridLayout);
            app.RunTrackingPanel.Title = 'Run Tracking';
            app.GridLayout14_2 = uigridlayout(app.RunTrackingPanel);
            app.MissingTrackingDataNotice = uilabel(app.GridLayout14_2);
            app.MissingTrackingDataNotice.Interpreter = 'html';
            app.MissingTrackingDataNotice.Text = {'The selected Trial is missing a corresponding tracking data file for the current Tracking Provider platform.'; ''; 'If this is intended, run the Tracking Pipeline using the currently selected platform before continue. Otherwise, your data might have been collected on and intended for use with a different tracking platform. In such case, change the Tracking Provider and see if things work.'};
            app.TrackVideoButton = uibutton(app.GridLayout14_2, 'push');
            app.TrackVideoButton.ButtonPushedFcn = createCallbackFcn(app, @TrackVideoButtonPushed, true);
            app.TrackVideoButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'scan-search.svg');
            app.TrackVideoButton.Text = 'Track Video';
            app.PopulationAnalysisPanel = uipanel(app.DatasetToolsGrid);
            app.GridLayout5_2 = uigridlayout(app.PopulationAnalysisPanel);
            app.BatchDataSelectTree = uitree(app.GridLayout5_2, 'checkbox');
            app.BatchDataSelectTree.Tooltip = {'Select trials you want to include in the population analysis'};
            app.BatchDataSelectRootNode = uitreenode(app.BatchDataSelectTree);
            app.BatchDataSelectRootNode.Text = '/ (Select Data to Include)';
            % Assign Checked Nodes
            app.BatchDataSelectTree.CheckedNodes = [app.BatchDataSelectRootNode];
            app.GridLayout9 = uigridlayout(app.GridLayout5_2);
            app.SubjectsDemographicsButton = uibutton(app.GridLayout9, 'push');
            app.SubjectsDemographicsButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectsDemographicsButtonPushed, true);
            app.SubjectsDemographicsButton.Tooltip = {'Diversity summary of subjects included in the population'};
            app.SubjectsDemographicsButton.Text = 'Subjects'' Demographics';
            app.ExperimentOverviewButton = uibutton(app.GridLayout9, 'push');
            app.ExperimentOverviewButton.ButtonPushedFcn = createCallbackFcn(app, @ExperimentOverviewButtonPushed, true);
            app.ExperimentOverviewButton.Text = 'Experiment Overview';
            app.PlacePreferencePopulationAnalysisButton = uibutton(app.GridLayout9, 'push');
            app.PlacePreferencePopulationAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PlacePreferencePopulationAnalysisButtonPushed, true);
            app.PlacePreferencePopulationAnalysisButton.Tooltip = {'Run population analysis on the selected trials'};
            app.PlacePreferencePopulationAnalysisButton.Text = 'Place Preference - Population Analysis';
            app.ActivitybyStimuliPopulationAnalysisButton = uibutton(app.GridLayout9, 'push');
            app.ActivitybyStimuliPopulationAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ActivitybyStimuliPopulationAnalysisButtonPushed, true);
            app.ActivitybyStimuliPopulationAnalysisButton.Text = 'Activity by Stimuli - Population Analysis';
            app.DatasetsSelectorPanel = uipanel(app.GridLayout3);
            app.ProjectSelectionGridLayout = uigridlayout(app.DatasetsSelectorPanel);
            app.DataProcessingPlatformPanel = uipanel(app.ProjectSelectionGridLayout);
            app.DataProcessingPlatformPanel.Title = 'Data Processing Platform';
            app.GridLayout19 = uigridlayout(app.DataProcessingPlatformPanel);
            app.DataProcessingPlatformDropDown = uidropdown(app.GridLayout19);
            app.DataProcessingPlatformDropDown.Items = {};
            app.DataProcessingPlatformDropDown.ValueChangedFcn = createCallbackFcn(app, @DataProcessingPlatformDropDownValueChanged, true);
            app.DataProcessingPlatformDropDown.Placeholder = 'The tracking platform/provider for these datasets';
            app.DataProcessingPlatformDropDown.Value = {};
            app.TrackingProviderLabel = uilabel(app.GridLayout19);
            app.TrackingProviderLabel.Text = 'Tracking Provider';
            app.TrackingProviderInfoButton = uiimage(app.GridLayout19);
            app.TrackingProviderInfoButton.ImageClickedFcn = createCallbackFcn(app, @TrackingProviderInfoButtonClicked, true);
            app.TrackingProviderInfoButton.Tooltip = {'Click to learn more'};
            app.TrackingProviderInfoButton.ImageSource = fullfile(pathToMLAPP, 'assets', 'icons', 'message-circle-question-mark.svg');
            app.ProjectRootSelect = FolderSelectorWithDropdown(app.ProjectSelectionGridLayout);
            app.ProjectRootSelect.DropDownLabel = 'Datasets';
            app.ProjectRootSelect.DropDownPlaceholder = 'EthoVision Project';
            app.ProjectRootSelect.DropdownSortAscend = false;
            app.ProjectRootSelect.PanelTitle = ' Project Root';
            app.ProjectRootSelect.PanelTitleFontSize = 14;
            app.ProjectRootSelect.Placeholder = 'Path to your project root. This is the folder that contains one or more datasets. Each dataset can have one or more recordings/trials.';
            app.ProjectRootSelect.ContentDropDownValueChangedFcn = createCallbackFcn(app, @ProjectRootSelectContentDropDownValueChanged, true);
            app.QuickActionsPanel = uipanel(app.ProjectSelectionGridLayout);
            app.QuickActionsPanel.Title = ' Quick Actions';
            app.QuickActionGrid = uigridlayout(app.QuickActionsPanel);
            app.GridLayout15 = uigridlayout(app.QuickActionGrid);
            app.OpenFolderInFileManager = uibutton(app.GridLayout15, 'push');
            app.OpenFolderInFileManager.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderInFileManagerButtonPushed, true);
            app.OpenFolderInFileManager.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'folder-tree.svg');
            app.OpenFolderInFileManager.Tooltip = {'Open this dataset folder in your default File Manager'};
            app.OpenFolderInFileManager.Text = '';
            app.GridLayout15_2 = uigridlayout(app.QuickActionGrid);
            app.CompressProjectFolderGrid = uibutton(app.GridLayout15_2, 'push');
            app.CompressProjectFolderGrid.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'folder-archive.svg');
            app.CompressProjectFolderGrid.Tooltip = {'Compress project folder to ZIP archive'};
            app.CompressProjectFolderGrid.Text = '';
            app.RunVideoTrackingToolGrid = uigridlayout(app.QuickActionGrid);
            app.BatchRunVideoTrackingButton = uibutton(app.RunVideoTrackingToolGrid, 'push');
            app.BatchRunVideoTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @BatchRunVideoTrackingButtonPushed, true);
            app.BatchRunVideoTrackingButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'scan-search.svg');
            app.BatchRunVideoTrackingButton.Enable = 'off';
            app.BatchRunVideoTrackingButton.Tooltip = {'Run tracking on all videos/trials in this dataset, using the selected Tracking Provider'};
            app.BatchRunVideoTrackingButton.Text = 'Videos Tracking';
            app.SplitMultipleArenaToolGrid = uigridlayout(app.QuickActionGrid);
            app.BatchSplitMultiArenaButton = uibutton(app.SplitMultipleArenaToolGrid, 'push');
            app.BatchSplitMultiArenaButton.ButtonPushedFcn = createCallbackFcn(app, @BatchSplitMultiArenaButtonPushed, true);
            app.BatchSplitMultiArenaButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'squares-intersect.svg');
            app.BatchSplitMultiArenaButton.Enable = 'off';
            app.BatchSplitMultiArenaButton.Tooltip = {'Split all multiple-arena trials within this dataset into single-arena trials with the arena names as suffix'};
            app.BatchSplitMultiArenaButton.Text = 'Split Multi-Arena';
            % Show the figure after all components are created
            app.PlacePrefDataAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlacePrefDataGUI_main(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PlacePrefDataAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PlacePrefDataAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlacePrefDataAnalysisUIFigure)
        end
    end
end